classdef SCAPE_datajoint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ListBox                   matlab.ui.control.ListBox
        RecorderDropDownLabel     matlab.ui.control.Label
        RecorderDropDown          matlab.ui.control.DropDown
        SCAPEConfigDropDownLabel  matlab.ui.control.Label
        SCAPEConfigDropDown       matlab.ui.control.DropDown
        ProjectDropDownLabel      matlab.ui.control.Label
        ProjectDropDown           matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [codepath,~,~] = fileparts(mfilename('fullpath'));
            addpath(codepath);
            
            selpath = uigetdir(matlabroot,'Select Session Folder');
            cd(selpath);
            
            filesInDataDirectory = dir('*info.mat');
            [~,idx] = sort({filesInDataDirectory.date});
            filesInDataDirectory = filesInDataDirectory(idx);
            runNames = cell(1,length(filesInDataDirectory));
            for i = 1:length(filesInDataDirectory)
                tmpname = filesInDataDirectory(i).name;
                runNames(i) = cellstr(tmpname(1:end-9));
            end
            app.ListBox.Items = runNames; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBox = uilistbox(app.UIFigure);
            app.RecorderDropDownLabel = uilabel(app.UIFigure);
            app.RecorderDropDownLabel.Text = 'Recorder';
            app.RecorderDropDown = uidropdown(app.UIFigure);
            app.SCAPEConfigDropDownLabel = uilabel(app.UIFigure);
            app.SCAPEConfigDropDownLabel.Text = 'SCAPE Config';
            app.SCAPEConfigDropDown = uidropdown(app.UIFigure);
            app.ProjectDropDownLabel = uilabel(app.UIFigure);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCAPE_datajoint

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end